classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ConvolutionTab               matlab.ui.container.Tab
        ExecuteConvolutionButton     matlab.ui.control.Button
        LoadImageConvolutionButton   matlab.ui.control.Button
        InputKernelConvolutionLabel  matlab.ui.control.Label
        SpinnerConvolution           matlab.ui.control.Spinner
        MaskSizenxnSpinnerLabel      matlab.ui.control.Label
        OutputImageConvolutionLabel  matlab.ui.control.Label
        InputImageConvolutionLabel   matlab.ui.control.Label
        ImageOutputConvolution       matlab.ui.control.Image
        UITableConvolution           matlab.ui.control.Table
        ImageInputConvolution        matlab.ui.control.Image
        ImageSmoothingTab            matlab.ui.container.Tab
        ImageSharpeningTab           matlab.ui.container.Tab
        ImageBrighteningTab          matlab.ui.container.Tab
        SaltPepperRemovalTab         matlab.ui.container.Tab
        PeriodicNoiseRemovalTab      matlab.ui.container.Tab
        WienerMotionBlurTab          matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get the screen size
            screenSize = get(groot, 'ScreenSize');
            
            % Set UIFigure position to fullscreen
            app.UIFigure.Position = [screenSize(1) screenSize(2) screenSize(3) screenSize(4)];

            app.UITableConvolution.Data = [0 0 0; 0 0 0; 0 0 0];
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % Get current size of the UIFigure
            figWidth = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            
            % Update the TabGroup to match UIFigure's size
            app.TabGroup.Position = [1, 1, figWidth, figHeight];
        end

        % Button pushed function: LoadImageConvolutionButton
        function LoadImageConvolutionButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputConvolution.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Value changed function: SpinnerConvolution
        function SpinnerConvolutionValueChanged(app, event)
            % Get the current spinner value
            value = app.SpinnerConvolution.Value;
            
            % Create a matrix of zeros with size (value x value)
            newData = zeros(value, value);
            
            % Update the UITableConvolution with the new data
            app.UITableConvolution.Data = newData;
        end

        % Button pushed function: ExecuteConvolutionButton
        function ExecuteConvolutionButtonPushed(app, event)
            % Retrieve the image path from ImageInputConvolution
            imagePath = app.ImageInputConvolution.ImageSource;
            
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
            
            % Ensure the image is of valid type (e.g., convert double to uint8 if needed)
            if ~isa(imageMatrix, 'uint8')
                imageMatrix = im2uint8(imageMatrix);
            end
            
            % Get the convolution mask from UITableConvolution
            mask = app.UITableConvolution.Data;
            
            % Ensure the mask is a valid square matrix
            [rows, cols] = size(mask);
            if rows ~= cols
                uialert(app.UIFigure, 'The convolution mask must be square (e.g., 3x3, 5x5).', 'Error');
                return;
            end
            
            % Apply the convolution function
            resultImage = convolution(imageMatrix, mask);
            
            % Convert resultImage to uint8 if necessary
            if ~isa(resultImage, 'uint8')
                resultImage = im2uint8(resultImage);
            end

             % Display the result in the ImageOutputConvolution component
            if size(resultImage, 3) == 1
                % If it's grayscale, convert to RGB for display
                resultImage = cat(3, resultImage, resultImage, resultImage);
            end
            
            app.ImageOutputConvolution.ImageSource = resultImage; % Correctly set the ImageSource
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.ImageInputConvolution = uiimage(app.ConvolutionTab);
            app.UITableConvolution = uitable(app.ConvolutionTab);
            app.UITableConvolution.ColumnName = '';
            app.UITableConvolution.RowName = {};
            app.UITableConvolution.ColumnEditable = true;
            app.ImageOutputConvolution = uiimage(app.ConvolutionTab);
            app.InputImageConvolutionLabel = uilabel(app.ConvolutionTab);
            app.InputImageConvolutionLabel.Text = 'Input Image';
            app.OutputImageConvolutionLabel = uilabel(app.ConvolutionTab);
            app.OutputImageConvolutionLabel.Text = 'Output Image';
            app.MaskSizenxnSpinnerLabel = uilabel(app.ConvolutionTab);
            app.MaskSizenxnSpinnerLabel.Text = 'Mask Size (n x n)';
            app.SpinnerConvolution = uispinner(app.ConvolutionTab);
            app.SpinnerConvolution.Limits = [1 Inf];
            app.SpinnerConvolution.ValueChangedFcn = createCallbackFcn(app, @SpinnerConvolutionValueChanged, true);
            app.SpinnerConvolution.Value = 3;
            app.InputKernelConvolutionLabel = uilabel(app.ConvolutionTab);
            app.InputKernelConvolutionLabel.Text = 'Input Kernel';
            app.LoadImageConvolutionButton = uibutton(app.ConvolutionTab, 'push');
            app.LoadImageConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageConvolutionButtonPushed, true);
            app.LoadImageConvolutionButton.Text = 'Load Image';
            app.ExecuteConvolutionButton = uibutton(app.ConvolutionTab, 'push');
            app.ExecuteConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteConvolutionButtonPushed, true);
            app.ExecuteConvolutionButton.Text = 'Execute';
            app.ImageSmoothingTab = uitab(app.TabGroup);
            app.ImageSmoothingTab.Title = 'Image Smoothing';
            app.ImageSharpeningTab = uitab(app.TabGroup);
            app.ImageSharpeningTab.Title = 'Image Sharpening';
            app.ImageBrighteningTab = uitab(app.TabGroup);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.SaltPepperRemovalTab = uitab(app.TabGroup);
            app.SaltPepperRemovalTab.Title = 'Salt & Pepper Removal';
            app.PeriodicNoiseRemovalTab = uitab(app.TabGroup);
            app.PeriodicNoiseRemovalTab.Title = 'Periodic Noise Removal';
            app.WienerMotionBlurTab = uitab(app.TabGroup);
            app.WienerMotionBlurTab.Title = 'Wiener Motion Blur';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end